classdef ProjectApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExperimentalPanel           matlab.ui.container.Panel
        FS4BandpassRippleRpSlider   matlab.ui.control.Slider
        FilterOrdernLabel_4         matlab.ui.control.Label
        D4BandpassRippleRpSlider    matlab.ui.control.Slider
        FilterOrdernLabel_3         matlab.ui.control.Label
        A3BandpassRippleRpSlider    matlab.ui.control.Slider
        FilterOrdernLabel_2         matlab.ui.control.Label
        D3BandpassRippleRpSlider    matlab.ui.control.Slider
        FilterOrdernLabel           matlab.ui.control.Label
        D3FilterOrdernSpinner       matlab.ui.control.Spinner
        FilterOrdernSpinnerLabel    matlab.ui.control.Label
        F436099HzLabel              matlab.ui.control.Label
        D429366HzLabel              matlab.ui.control.Label
        A322000HzLabel              matlab.ui.control.Label
        D314683HzLabel              matlab.ui.control.Label
        DeltaFEditField             matlab.ui.control.NumericEditField
        DeltaFEditFieldLabel        matlab.ui.control.Label
        SignalViewSwitch            matlab.ui.control.Switch
        SignalViewSwitchLabel       matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        FS4MagnitudeEditField       matlab.ui.control.NumericEditField
        F4MagnitudeEditFieldLabel   matlab.ui.control.Label
        D4MagnitudeEditField        matlab.ui.control.NumericEditField
        D4MagnitudeEditFieldLabel   matlab.ui.control.Label
        A3MagnitudeEditField        matlab.ui.control.NumericEditField
        A3MagnitudeEditFieldLabel   matlab.ui.control.Label
        D3MagnitudeEditField        matlab.ui.control.NumericEditField
        D3MagnitudeEditFieldLabel   matlab.ui.control.Label
        FS4FilterOrdernSpinner      matlab.ui.control.Spinner
        FilterOrdernSpinnerLabel_4  matlab.ui.control.Label
        D4FilterOrdernSpinner       matlab.ui.control.Spinner
        FilterOrdernSpinnerLabel_3  matlab.ui.control.Label
        A3FilterOrdernSpinner       matlab.ui.control.Spinner
        FilterOrdernSpinnerLabel_2  matlab.ui.control.Label
        FilterUIAxes                matlab.ui.control.UIAxes
        SignalUIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalInputSignal;
        samplingFrequency;
        samplingPeriod;
        signalLength;
        time;
        f;
        f_kHz;
        
        D3;
        A3;
        D4;
        F_sharp_4;
        
        D3_int;
        A3_int;
        D4_int;
        F_sharp_4_int;
        
        note_freq;
        note_freq_int;
        
        filteredSignal;
        freqResponse;
        
        magnitudes;
    end
    
    methods (Access = private)
        
        %% Graph in time domain
        function plotTimeDomain(app)
            hold(app.SignalUIAxes, "off");
            plot(app.SignalUIAxes, app.time,app.filteredSignal);
            axis(app.SignalUIAxes,"tight");
            title(app.SignalUIAxes,'Time Domain Signal');           % labels
            xlabel(app.SignalUIAxes,'t (s)');                       % labels
            ylabel(app.SignalUIAxes,'y(t)');                        % labels
        end
        
        %% Graph with DFT/FFT
        function plotFrequencyDomain(app)        
            hold(app.FilterUIAxes, "off");
            plot(app.SignalUIAxes,app.f_kHz, app.freqResponse)                         % plot 
            axis(app.SignalUIAxes,[0  max(app.f_kHz) 0 max(app.freqResponse)])        % axis details
            axis(app.SignalUIAxes,"tight");
            title(app.SignalUIAxes,'Frequency Domain Signal');          % labels
            xlabel(app.SignalUIAxes,'F (kHz)')                          % labels
            ylabel(app.SignalUIAxes,'Y(F)')                             % labels
            xlim(app.SignalUIAxes, [0, app.F_sharp_4/1000*1.1])
        end
        
        function applyFFT(app)
            Y = fft(app.filteredSignal);                            % Discrete Fourier transform
            F1 = abs(Y/app.signalLength);                           % frequency
            app.freqResponse = F1(1:app.signalLength/2+1);                        % half of frequency
            app.freqResponse(2:end-1) = 2*app.freqResponse(2:end-1);                            % Discrete Fourier transform
        end
        
        function plotPassbandFrequencyRespnse(app, filterOrder, passbandRipple, frequency, deltaFrequencyPercent, noteName)
            
%             deltaF = frequency * deltaFrequencyPercent / 100;
%                     
%             [z1,p1,k1] = cheby1(filterOrder,passbandRipple,(frequency + deltaF)/(app.samplingFrequency/2), 'low');
%             [z2,p2,k2] = cheby1(filterOrder,passbandRipple,(frequency - deltaF)/(app.samplingFrequency/2), 'high');
% 
%             lpf = zpk(z1,p1,k1);
%             hpf = zpk(z2,p2,k2);
%             
%             sys = series(lpf,hpf);
%             [z,p,k] = zpkdata(sys, 'v');
%             
%             sos = zp2sos(z,p,k)
%             h = fvtool(sos);
            
%             idx = find(freq < (10 * frequency));
%             plot(app.FilterUIAxes,freq(idx),db(h(idx)));
%             
%             ttl = sprintf('Magnitude Response (dB) of %s Passband Filter', noteName);
%             
%             hold(app.FilterUIAxes, "on");
%             
%             for note_frequency = app.note_freq
%                 absDiff = abs(freq-note_frequency);
%                 minPt = min(absDiff);
%                 plot(app.FilterUIAxes,note_frequency, db(h(absDiff == minPt)), "r*");
%             end
% 
%             xline(app.FilterUIAxes,app.note_freq);
%             
%             yline(app.FilterUIAxes,-3);
%             xlabel(app.FilterUIAxes, 'Frequency (Hz)');
%             ylabel(app.FilterUIAxes, 'Magnitude (dB)');
%             title(app.FilterUIAxes, ttl);
%             grid(app.FilterUIAxes,"on");
%             axis(app.FilterUIAxes,"tight");
%             ylim(app.FilterUIAxes, [-10 1]);
%             hold(app.FilterUIAxes, "off");
        end

        function ApplyCompression(app)            
            D3FilterOrder = app.D3FilterOrdernSpinner.Value;
            A3FilterOrder = app.A3FilterOrdernSpinner.Value;
            D4FilterOrder = app.D4FilterOrdernSpinner.Value;
            FS4FilterOrder = app.FS4FilterOrdernSpinner.Value;
            
            D3BandpassRipple = app.D3BandpassRippleRpSlider.Value;
            A3BandpassRipple = app.A3BandpassRippleRpSlider.Value;
            D4BandpassRipple = app.D4BandpassRippleRpSlider.Value;
            FS4BandpassRipple = app.FS4BandpassRippleRpSlider.Value;
            
            
            filterOrders = [D3FilterOrder, A3FilterOrder, D4FilterOrder, FS4FilterOrder];
            bandpassRipples = [D3BandpassRipple, A3BandpassRipple, D4BandpassRipple, FS4BandpassRipple];
            
            filteredSignals = zeros(app.signalLength,4);
            normalizedSignals = zeros(app.signalLength,4);
            magnitudes = zeros(4,1);
            
            for i = 1:length(app.note_freq)
                app.filteredSignal = app.originalInputSignal;
                frequency = app.note_freq(i);
                frequencyIndex = app.note_freq_int(i);
                filterOrder = filterOrders(i);
                bandpassRipple = bandpassRipples(i);
                magnitudeNumBox = app.magnitudes(i);
                
                frequencyDelta = frequency * 10 / 100;
            
            
                normalizedFrequency = (frequency + frequencyDelta)/ (app.samplingFrequency/2);
                [b, a] = cheby1(filterOrder,bandpassRipple,normalizedFrequency,'low');
                app.filteredSignal = filter(b,a,app.originalInputSignal);
                
                normalizedFrequency = (frequency - frequencyDelta)/ (app.samplingFrequency/2);
                [b, a] = cheby1(filterOrder,bandpassRipple,normalizedFrequency,'high');
                app.filteredSignal = filter(b,a,app.filteredSignal);

                applyFFT(app);
                
                filteredSignalPk = app.freqResponse(frequencyIndex);
                filteredSignals(:,i) = app.filteredSignal;
                magnitudes(i) = filteredSignalPk;
                
                normalizedSignal = app.filteredSignal / filteredSignalPk;
                normalizedSignals(:,i) = normalizedSignal;
            end

            app.filteredSignal = sum(normalizedSignals,2) * max(magnitudes);
            SignalViewSwitchValueChanged(app);
            
            app.D3MagnitudeEditField.Value = magnitudes(1);
            app.A3MagnitudeEditField.Value = magnitudes(2);
            app.D4MagnitudeEditField.Value = magnitudes(3);
            app.FS4MagnitudeEditField.Value = magnitudes(4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Import audio data
            app.originalInputSignal = importdata('ENGR_362_guitar_Fs_is_48000_Hz.txt');
            app.samplingFrequency = 48000;
            app.samplingPeriod = 1/app.samplingFrequency;
            app.signalLength = length(app.originalInputSignal(:,1));                    % length of signal
            app.time = (0:app.signalLength-1) * app.samplingPeriod ;
            
            app.f = app.samplingFrequency*(0:(app.signalLength/2))/app.signalLength;    % freq vector [Hz]
            app.f_kHz = app.f/1000;                                                     % freq vector [kHz]
            
            app.D3 = 146.83;                            % freq of note D3 [Hz]
            app.D3_int = round(app.D3/max(app.f)*length(app.f));     % associated integer to above freq
            
            app.A3 = 220.00;                            % freq of note A3 [Hz]
            app.A3_int = round(app.A3/max(app.f)*length(app.f));     % associated integer to above freq
            
            app.D4 = 293.66;                            % freq of note D4 [Hz]
            app.D4_int = round(app.D4/max(app.f)*length(app.f));     % associated integer to above freq
            
            app.F_sharp_4 = 369.99;                     % freq of note F#4 [Hz]
            app.F_sharp_4_int = round(app.F_sharp_4/max(app.f)*length(app.f));   % associated integer to above freq
            
            app.note_freq = [app.D3 app.A3 app.D4 app.F_sharp_4];       % vector of all note freqs
            app.note_freq_int = [app.D3_int app.A3_int app.D4_int app.F_sharp_4_int]; % vector of all int note freqs
            
            app.D3FilterOrdernSpinner.Limits = [1, 100];
            app.A3FilterOrdernSpinner.Limits = [1, 100];
            app.D4FilterOrdernSpinner.Limits = [1, 100];
            app.FS4FilterOrdernSpinner.Limits = [1, 100];
            
            app.magnitudes = [app.D3MagnitudeEditField, app.A3MagnitudeEditField, app.D4MagnitudeEditField, app.FS4MagnitudeEditField];
            
            app.ResetButtonPushed(app);
        end

        % Callback function
        function PlaySoundButtonPushed(app, event)
            %% Play sound of raw audio data
            soundsc(app.filteredSignal, app.samplingFrequency);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.filteredSignal = app.originalInputSignal;

            app.D3FilterOrdernSpinner.Value = 1;
            app.A3FilterOrdernSpinner.Value = 1;
            app.D4FilterOrdernSpinner.Value = 1;
            app.FS4FilterOrdernSpinner.Value = 1;

            app.D3BandpassRippleRpSlider.Value = 0.1;
            app.A3BandpassRippleRpSlider.Value = 0.1;
            app.D4BandpassRippleRpSlider.Value = 0.1;
            app.FS4BandpassRippleRpSlider.Value = 0.1;
            
            SignalViewSwitchValueChanged(app);
            
            for mag = app.magnitudes
                mag.Value = 0;
            end
        end

        % Value changed function: SignalViewSwitch
        function SignalViewSwitchValueChanged(app, event)
            value = app.SignalViewSwitch.Value;
            if (value == "Time Domain")
                plotTimeDomain(app);
            else            
                applyFFT(app);
                plotFrequencyDomain(app);
            end
        end

        % Value changed function: D3BandpassRippleRpSlider
        function D3BandpassRippleRpSliderValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.D3FilterOrdernSpinner.Value,...
                app.D3BandpassRippleRpSlider.Value, app.D3, app.DeltaFEditField.Value, "D3");
            ApplyCompression(app);
            
        end

        % Value changed function: A3BandpassRippleRpSlider
        function A3BandpassRippleRpSliderValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.A3FilterOrdernSpinner.Value,...
                app.A3BandpassRippleRpSlider.Value, app.A3, app.DeltaFEditField.Value, "A3");
            ApplyCompression(app);
            
        end

        % Value changed function: D4BandpassRippleRpSlider
        function D4BandpassRippleRpSliderValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.D4FilterOrdernSpinner.Value,...
                app.D4BandpassRippleRpSlider.Value, app.D4, app.DeltaFEditField.Value, "D4");
            ApplyCompression(app);
            
        end

        % Value changed function: FS4BandpassRippleRpSlider
        function FS4BandpassRippleRpSliderValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.FS4FilterOrdernSpinner.Value,...
                app.FS4BandpassRippleRpSlider.Value, app.F_sharp_4, app.DeltaFEditField.Value, "F#4");
            ApplyCompression(app);
            
        end

        % Value changed function: D3FilterOrdernSpinner
        function D3FilterOrdernSpinnerValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.D3FilterOrdernSpinner.Value,...
                app.D3BandpassRippleRpSlider.Value, app.D3, app.DeltaFEditField.Value, "D3");
            ApplyCompression(app);
            
        end

        % Value changed function: A3FilterOrdernSpinner
        function A3FilterOrdernSpinnerValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.A3FilterOrdernSpinner.Value,...
                app.A3BandpassRippleRpSlider.Value, app.A3, app.DeltaFEditField.Value, "A3");
            ApplyCompression(app);
            
        end

        % Value changed function: D4FilterOrdernSpinner
        function D4FilterOrdernSpinnerValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.D4FilterOrdernSpinner.Value,...
                app.D4BandpassRippleRpSlider.Value, app.D4, app.DeltaFEditField.Value, "D4");
            ApplyCompression(app);
            
        end

        % Value changed function: FS4FilterOrdernSpinner
        function FS4FilterOrdernSpinnerValueChanged(app, event)
            plotPassbandFrequencyRespnse(app, app.FS4FilterOrdernSpinner.Value,...
                app.FS4BandpassRippleRpSlider.Value, app.F_sharp_4, app.DeltaFEditField.Value, "F#4");
            ApplyCompression(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalUIAxes = uiaxes(app.UIFigure);
            title(app.SignalUIAxes, 'Title')
            xlabel(app.SignalUIAxes, 'X')
            ylabel(app.SignalUIAxes, 'Y')
            zlabel(app.SignalUIAxes, 'Z')
            app.FilterUIAxes = uiaxes(app.UIFigure);
            title(app.FilterUIAxes, 'Title')
            xlabel(app.FilterUIAxes, 'X')
            ylabel(app.FilterUIAxes, 'Y')
            zlabel(app.FilterUIAxes, 'Z')
            app.ExperimentalPanel = uipanel(app.UIFigure);
            app.ExperimentalPanel.Title = 'Experimental Panel';
            app.FilterOrdernSpinnerLabel_2 = uilabel(app.ExperimentalPanel);
            app.FilterOrdernSpinnerLabel_2.Text = 'Filter Order (n)';
            app.A3FilterOrdernSpinner = uispinner(app.ExperimentalPanel);
            app.A3FilterOrdernSpinner.ValueChangedFcn = createCallbackFcn(app, @A3FilterOrdernSpinnerValueChanged, true);
            app.FilterOrdernSpinnerLabel_3 = uilabel(app.ExperimentalPanel);
            app.FilterOrdernSpinnerLabel_3.Text = 'Filter Order (n)';
            app.D4FilterOrdernSpinner = uispinner(app.ExperimentalPanel);
            app.D4FilterOrdernSpinner.ValueChangedFcn = createCallbackFcn(app, @D4FilterOrdernSpinnerValueChanged, true);
            app.FilterOrdernSpinnerLabel_4 = uilabel(app.ExperimentalPanel);
            app.FilterOrdernSpinnerLabel_4.Text = 'Filter Order (n)';
            app.FS4FilterOrdernSpinner = uispinner(app.ExperimentalPanel);
            app.FS4FilterOrdernSpinner.ValueChangedFcn = createCallbackFcn(app, @FS4FilterOrdernSpinnerValueChanged, true);
            app.D3MagnitudeEditFieldLabel = uilabel(app.ExperimentalPanel);
            app.D3MagnitudeEditFieldLabel.Text = 'D3 Magnitude';
            app.D3MagnitudeEditField = uieditfield(app.ExperimentalPanel, 'numeric');
            app.D3MagnitudeEditField.Editable = 'off';
            app.A3MagnitudeEditFieldLabel = uilabel(app.ExperimentalPanel);
            app.A3MagnitudeEditFieldLabel.Text = 'A3 Magnitude';
            app.A3MagnitudeEditField = uieditfield(app.ExperimentalPanel, 'numeric');
            app.A3MagnitudeEditField.Editable = 'off';
            app.D4MagnitudeEditFieldLabel = uilabel(app.ExperimentalPanel);
            app.D4MagnitudeEditFieldLabel.Text = 'D4 Magnitude';
            app.D4MagnitudeEditField = uieditfield(app.ExperimentalPanel, 'numeric');
            app.D4MagnitudeEditField.Editable = 'off';
            app.F4MagnitudeEditFieldLabel = uilabel(app.ExperimentalPanel);
            app.F4MagnitudeEditFieldLabel.Text = 'F#4 Magnitude';
            app.FS4MagnitudeEditField = uieditfield(app.ExperimentalPanel, 'numeric');
            app.FS4MagnitudeEditField.Editable = 'off';
            app.ResetButton = uibutton(app.ExperimentalPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SignalViewSwitchLabel = uilabel(app.ExperimentalPanel);
            app.SignalViewSwitchLabel.Text = 'Signal View';
            app.SignalViewSwitch = uiswitch(app.ExperimentalPanel, 'slider');
            app.SignalViewSwitch.Items = {'Time Domain', 'Frequency Domain'};
            app.SignalViewSwitch.ValueChangedFcn = createCallbackFcn(app, @SignalViewSwitchValueChanged, true);
            app.SignalViewSwitch.Value = 'Time Domain';
            app.DeltaFEditFieldLabel = uilabel(app.ExperimentalPanel);
            app.DeltaFEditFieldLabel.Text = 'Delta F (+/- %)';
            app.DeltaFEditField = uieditfield(app.ExperimentalPanel, 'numeric');
            app.DeltaFEditField.Value = 10;
            app.D314683HzLabel = uilabel(app.ExperimentalPanel);
            app.D314683HzLabel.Text = 'D3 (146.83 Hz)';
            app.A322000HzLabel = uilabel(app.ExperimentalPanel);
            app.A322000HzLabel.Text = 'A3 (220.00 Hz)';
            app.D429366HzLabel = uilabel(app.ExperimentalPanel);
            app.D429366HzLabel.Text = 'D4 (293.66 Hz)';
            app.F436099HzLabel = uilabel(app.ExperimentalPanel);
            app.F436099HzLabel.Text = 'F#4 (360.99 Hz)';
            app.FilterOrdernSpinnerLabel = uilabel(app.ExperimentalPanel);
            app.FilterOrdernSpinnerLabel.Text = 'Filter Order (n)';
            app.D3FilterOrdernSpinner = uispinner(app.ExperimentalPanel);
            app.D3FilterOrdernSpinner.ValueChangedFcn = createCallbackFcn(app, @D3FilterOrdernSpinnerValueChanged, true);
            app.FilterOrdernLabel = uilabel(app.ExperimentalPanel);
            app.FilterOrdernLabel.Text = 'Bandpass Ripple (Rp)';
            app.D3BandpassRippleRpSlider = uislider(app.ExperimentalPanel);
            app.D3BandpassRippleRpSlider.Limits = [0 10];
            app.D3BandpassRippleRpSlider.ValueChangedFcn = createCallbackFcn(app, @D3BandpassRippleRpSliderValueChanged, true);
            app.FilterOrdernLabel_2 = uilabel(app.ExperimentalPanel);
            app.FilterOrdernLabel_2.Text = 'Bandpass Ripple (Rp)';
            app.A3BandpassRippleRpSlider = uislider(app.ExperimentalPanel);
            app.A3BandpassRippleRpSlider.Limits = [0 10];
            app.A3BandpassRippleRpSlider.ValueChangedFcn = createCallbackFcn(app, @A3BandpassRippleRpSliderValueChanged, true);
            app.FilterOrdernLabel_3 = uilabel(app.ExperimentalPanel);
            app.FilterOrdernLabel_3.Text = 'Bandpass Ripple (Rp)';
            app.D4BandpassRippleRpSlider = uislider(app.ExperimentalPanel);
            app.D4BandpassRippleRpSlider.Limits = [0 10];
            app.D4BandpassRippleRpSlider.ValueChangedFcn = createCallbackFcn(app, @D4BandpassRippleRpSliderValueChanged, true);
            app.FilterOrdernLabel_4 = uilabel(app.ExperimentalPanel);
            app.FilterOrdernLabel_4.Text = 'Bandpass Ripple (Rp)';
            app.FS4BandpassRippleRpSlider = uislider(app.ExperimentalPanel);
            app.FS4BandpassRippleRpSlider.Limits = [0 10];
            app.FS4BandpassRippleRpSlider.ValueChangedFcn = createCallbackFcn(app, @FS4BandpassRippleRpSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end